classdef Mutsumis_Music_Player < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu_playControl               matlab.ui.container.Menu
        Menu_play                      matlab.ui.container.Menu
        Menu_pause                     matlab.ui.container.Menu
        Menu_resume                    matlab.ui.container.Menu
        Menu_stop                      matlab.ui.container.Menu
        Menu_preMusic                  matlab.ui.container.Menu
        Menu_nextMusic                 matlab.ui.container.Menu
        Menu_contact                   matlab.ui.container.Menu
        Menu_email                     matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Button_restore                 matlab.ui.control.Button
        Panel_hidden                   matlab.ui.container.Panel
        Image_birth                    matlab.ui.control.Image
        Label_lora                     matlab.ui.control.Label
        Spinner_lora                   matlab.ui.control.Spinner
        Button_videoPlay_2             matlab.ui.control.Button
        Button_quit                    matlab.ui.control.Button
        Button_videoStop               matlab.ui.control.Button
        Button_videoPlay               matlab.ui.control.Button
        Label_birthday                 matlab.ui.control.Label
        Image_doughnut                 matlab.ui.control.Image
        Image_mana                     matlab.ui.control.Image
        UIAxes_video                   matlab.ui.control.UIAxes
        DatePicker_date                matlab.ui.control.DatePicker
        Label_9                        matlab.ui.control.Label
        Image_record4                  matlab.ui.control.Image
        Image_mutsumi2                 matlab.ui.control.Image
        Panel_volumeAdjust             matlab.ui.container.Panel
        Slider_volumeAdjust            matlab.ui.control.Slider
        Label_7                        matlab.ui.control.Label
        Image_gif                      matlab.ui.control.Image
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_1                       matlab.ui.control.Button
        Label_name                     matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Tab_musicList                  matlab.ui.container.Tab
        Panel_doubleCck                matlab.ui.container.Panel
        DoubleClickImageSurpriseLabel  matlab.ui.control.Label
        Button_importMusic             matlab.ui.control.Button
        Image_mutsumi7                 matlab.ui.control.Image
        Image_mutsumi4                 matlab.ui.control.Image
        Image_mutsumi6                 matlab.ui.control.Image
        ListBox_songs                  matlab.ui.control.ListBox
        Label_2                        matlab.ui.control.Label
        Tab_musicEffect                matlab.ui.container.Tab
        Image_mutsumi1                 matlab.ui.control.Image
        Panel_musicEffect              matlab.ui.container.Panel
        DropDown_highEq                matlab.ui.control.DropDown
        Label_8                        matlab.ui.control.Label
        Switch_humanVoice              matlab.ui.control.Switch
        Label_6                        matlab.ui.control.Label
        DropDown_reverberation         matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        DropDown_speed                 matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDown_musicStyle            matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        Tab_waveForm                   matlab.ui.container.Tab
        UIAxes_current                 matlab.ui.control.UIAxes
        UIAxes_all                     matlab.ui.control.UIAxes
        Tab_surprise                   matlab.ui.container.Tab
        Image_surprise5                matlab.ui.control.Image
        Image_surprise4                matlab.ui.control.Image
        Image_surprise3                matlab.ui.control.Image
        Image_surprise2                matlab.ui.control.Image
        Image_surprise1                matlab.ui.control.Image
        Panel_surprise0                matlab.ui.container.Panel
        Label_surprise0                matlab.ui.control.Label
        Image_mutsumi5                 matlab.ui.control.Image
        UIAxes_circle                  matlab.ui.control.UIAxes
        Panel_progress                 matlab.ui.container.Panel
        Label_time                     matlab.ui.control.Label
        Slider_progress                matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        Panel_playControl              matlab.ui.container.Panel
        Image_nextMusic                matlab.ui.control.Image
        Image_preMusic                 matlab.ui.control.Image
        Image_stop                     matlab.ui.control.Image
        Image_resume                   matlab.ui.control.Image
        Image_pause                    matlab.ui.control.Image
        Image_play                     matlab.ui.control.Image
        Button_nextMusic               matlab.ui.control.Button
        Button_preMusic                matlab.ui.control.Button
        Button_stop                    matlab.ui.control.Button
        Button_resume                  matlab.ui.control.Button
        Button_pause                   matlab.ui.control.Button
        Button_play                    matlab.ui.control.Button
        Image_cucumber                 matlab.ui.control.Image
        Label_title                    matlab.ui.control.Label
        Image_record2                  matlab.ui.control.Image
        Image_record1                  matlab.ui.control.Image
        Image_record3                  matlab.ui.control.Image
        Image_bg                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        FileNames={}
        PathName=""
        FileCount=0
        SampleData=[]
        SampleDataInitial=[]
        Fs=-1
        CurrentSample=1
        Player={}
        EqType='Normal' % 音乐风格类型 'Normal','Jazz','Rock','Metal','Bass'
        IsRemoveHumanVoice=false % 是否进行人声消除，true:消除，false:不消除
        ReverbValue=0 % 混响参数（秒）
        PlaySpeed=1 % 播放速度
        Volume=0.7 % 播放音量
        imageData % 存储原始图片数据
        imageCenter=[575,575] % record3旋转中心
        currentAngle % 当前图片旋转角度
        isRotating=[false,false] % 旋转标志变量
        colors = [
            0.5294, 0.8078, 0.9216; % 天蓝色
            0, 1, 1;                % 青色
            0, 0.5019, 0.5019;      % 蓝绿色
            0, 0, 0.5019;           % 深蓝色
            0.5961, 1, 0.5961;      % 薄荷绿
            0.8, 0.8, 1;            % 蓝紫色
            0.6784, 0.8471, 0.9020; % 冰蓝色
            0, 0.3922, 0;           % 深绿色
            0.2510, 0.8784, 0.8157; % 青绿色
            0.4157, 0.3529, 0.8039  % 灰蓝色
        ] % 冷色调卡
        highEq='None' % 高级音效
        isPlaying=false % 判断是否正在播放
        appRoot = fileparts(mfilename('fullpath')) % 获取当前主文件的目录
        audioPlay % 存储音频文件列表
        curIdPlay=1 % 当前播放的音频文件索引
        audioPause % 存储音频文件列表
        curIdPause=1 % 当前播放的音频文件索引
        audioResume % 存储音频文件列表
        curIdResume=1 % 当前播放的音频文件索引
        audioStop % 存储音频文件列表
        curIdStop=1 % 当前播放的音频文件索引
        audioPre % 存储音频文件列表
        curIdPre=1 % 当前播放的音频文件索引
        audioNext % 存储音频文件列表
        curIdNext=1 % 当前播放的音频文件索引
        audioGif % 存储音频文件列表
        curIdGif=1 % 当前播放的音频文件索引
        audioMu6 % 存储音频文件列表
        curIdMu6=1 % 当前播放的音频文件索引
        audioMu4 % 存储音频文件列表
        curIdMu4=1 % 当前播放的音频文件索引
        audioMu7 % 存储音频文件列表
        curIdMu7=1 % 当前播放的音频文件索引
        lastClickTime=0 % 记录上一次点击的时间
        Player2={} % 彩蛋
        imageFolder='random pictures' % 图片文件夹路径
        imageFiles % 存储图片文件列表
        videoReader={} % 视频读取对象
        audioVideoPlayer={} % 视频音频对象
        displaySurprise=true % 展示隐藏彩蛋
        videoPlaying=false % 视频正在播放判定
        videoPaused=false % 视频暂停判定
        vAudioData % 音频数据
        vSampleRate % 采样频率
        videoIndex=0 % 视频索引
        imageFileBirth % 存储图片路径
    end
    
    methods (Access = private)     
        % 读取音乐数据并初始化播放数据
        function readMusicDataAndInit(app, filename)
            fullFilePath=[app.PathName, filename]; % 生成音乐文件路径名+文件名
            [sampleData, fs]=audioread(fullFilePath); % 读入音频文件数据
            app.SampleDataInitial=sampleData; % 存储原始音频文件采样数据
            app.SampleData=sampleData; % 存储播放数据
            app.Fs=fs; % 存储采样频率
            app.CurrentSample=1; % 初始化播放器的播放位置
        end
        
        % 初始化音乐播放器
        function initPlayer(app)
            % 1.处理Eq风格
            app.SampleData=app.SampleDataInitial;
            app.SampleData=setPopularEq(app.SampleData,app.EqType,app.Fs); 

            % 2.处理高级音效
            app.SampleData=setHighQualityEq(app.SampleData,app.highEq,app.Fs);

            % 3.处理人声
            if(app.IsRemoveHumanVoice)
                app.SampleData=cutDownHumanVoice(app.SampleData); % 调用人声消除函数
            end

            % 4.处理混响
            if(app.ReverbValue~=0) % 等于0时无混响，不需处理，否则要处理
                % 调用混响效果函数
                app.SampleData=getReverbSampleData(app.SampleData,app.ReverbValue,app.Fs);
            end

            % 5.处理音量
            app.SampleData=app.SampleData*app.Volume;
            
            % 6.播放数据非空时，实例化audioplayer对象
            if(~isempty(app.SampleData))
                app.Player=audioplayer(app.SampleData, app.Fs); % 实例化audioplayer对象
                app.Player.TimerFcn=@(~,~) Player_timeFcn(app); % 定义Timer回调函数
                app.Player.TimerPeriod=0.5; % 定义Timer回调周期
            else
                app.Player={};
            end

            % 7.处理播放速度
            if(~isempty(app.Player))
                app.Player.SampleRate=round(app.Fs*app.PlaySpeed);
            end
        end
        
        % 计时器回调函数，实时更新进度条及波形显示
        function Player_timeFcn(app)
            % 1.根据当前播放位置，设定进度条显示设置
            currentSample=app.Player.CurrentSample; % 当前播放到的数据点
            totalSamples=app.Player.TotalSamples; % 音乐文件总数据点数
            app.Slider_progress.Value=currentSample/(totalSamples+1); % 设定进度条当前值

            % 2.根据当前播放位置，设定文本标签显示效果
            % （1）获取当前播放位置显示文本
            currentSec=currentSample/app.Fs; % 当前播放到的时刻（单位：秒）
            [tHourStrCur,tMinStrCur,tSecStrCur]=sec2HourMinSec(currentSec); % 转换为时分秒
            currentStr=[tHourStrCur,':',tMinStrCur,':',tSecStrCur]; % 时分秒字符串
            % （2）获取总时长显示文本
            totalSec=totalSamples/app.Fs; % 音乐文件总时长
            [tHourStrTotal,tMinStrTotal,tSecStrTotal]=sec2HourMinSec(totalSec); % 转换为时分秒
            totalStr=[tHourStrTotal,':',tMinStrTotal,':',tSecStrTotal]; % 时分秒字符串
            % （3）时间进度标签显示
            timeLabelStr=[currentStr,' / ',totalStr]; % 时间进度标签文本
            app.Label_time.Text=timeLabelStr; % 显示时间进度标签
            
            % 3.波形显示
            % （1）获得总的数据
            tAll=(1:totalSamples)/app.Fs; % 从开始到结束所有时刻点
            tAll=tAll'; % 行向量变列向量
            xAll=app.SampleData(:,1); % 从开始到结束单通道中所有音频数据
            % （2）获得当前播放位置附近的显示区间的数据
            duraDisplay=2; % 当前播放位置附近显示的时长
            lengthDisplay=round(app.Fs*duraDisplay); % 当前播放位置附近显示的数据点数
            if currentSample+lengthDisplay-1<=totalSamples % 正常情况
                idxStart=currentSample; % 获得起始索引位置
            else % 显示区间段最后超过总数据点数，防止越界
                idxStart=totalSamples-lengthDisplay+1; % 获得起始索引位置
            end
            idxEnd=idxStart+lengthDisplay-1; % 获得显示的结束索引位置
            tDisplay=tAll(idxStart:idxEnd,1);
            xDisplay=xAll(idxStart:idxEnd,1);
            % （3）在时域波形table上面的坐标轴上绘图
            plot(app.UIAxes_all,tAll,xAll,'-k') % 绘制总图
            hold(app.UIAxes_all,'on') % 可叠加绘制开启
            plot(app.UIAxes_all,tDisplay,xDisplay,'-r') % 绘制当前播放位置附近的图
            hold(app.UIAxes_all,'off') % 可叠加绘制关闭
            % （4）在时域波形table下面的坐标轴上绘图
            plot(app.UIAxes_current,tDisplay,xDisplay,'-r') % 绘制当前播放附近的图

            % 4.gif动图随时间移动
            currentGifPos=app.Image_gif.Position;
            app.Image_gif.Position=[121+769*app.Slider_progress.Value, ...
                currentGifPos(2),currentGifPos(3),currentGifPos(4)];

            % 5.彩蛋circle
            minSample=min(xAll);
            maxSample=max(xAll);

            cla(app.UIAxes_circle);
            hold(app.UIAxes_circle, 'on');

            % 定义新的圆环参数
            theta = linspace(0, 2*pi, 100); % 角度
            r1 = 90 + floor((xAll(currentSample,1)-minSample)*50/(maxSample-minSample));
            r2 = 0.96*r1;
            r3 = 80 + floor((xAll(currentSample,1)-minSample)*40/(maxSample-minSample));
            r4 = 0.97*r3;
            r5 = 70 + floor((xAll(currentSample,1)-minSample)*30/(maxSample-minSample));
            r6 = 0.98*r5;
        
            % 计算外圆和内圆的坐标
            x_outer = r1 * cos(theta);
            y_outer = r1 * sin(theta);
            x_inner = r2 * cos(theta);
            y_inner = r2 * sin(theta);

            x2_outer = r3 * cos(theta);
            y2_outer = r3 * sin(theta);
            x2_inner = r4 * cos(theta);
            y2_inner = r4 * sin(theta);

            x3_outer = r5 * cos(theta);
            y3_outer = r5 * sin(theta);
            x3_inner = r6 * cos(theta);
            y3_inner = r6 * sin(theta);
        
            % 关闭坐标轴自动缩放
            app.UIAxes_circle.XLimMode = 'manual';
            app.UIAxes_circle.YLimMode = 'manual';
        
            % 设置坐标轴范围
            xlim(app.UIAxes_circle, [-140, 140]); % X 轴范围
            ylim(app.UIAxes_circle, [-140, 140]); % Y 轴范围
        
            % 绘制圆环
            color=app.colors(randi([1,10]),1:3);
            color1=app.colors(randi([1,10]),1:3);
            color2=app.colors(randi([1,10]),1:3);
            fill(app.UIAxes_circle, [x_outer, fliplr(x_inner)], [y_outer, fliplr(y_inner)], color, 'EdgeColor', [1,1,1]-color); % 填充圆环
            fill(app.UIAxes_circle, [x2_outer, fliplr(x2_inner)], [y2_outer, fliplr(y2_inner)], color1, 'EdgeColor', [1,1,1]-color1); % 填充圆环
            fill(app.UIAxes_circle, [x3_outer, fliplr(x3_inner)], [y3_outer, fliplr(y3_inner)], color2, 'EdgeColor', [1,1,1]-color2); % 填充圆环
            axis(app.UIAxes_circle, 'equal'); % 设置坐标轴比例相等
        
            % 隐藏坐标轴和边框
            app.UIAxes_circle.Color=[0.94,0.94,0.94];
            app.UIAxes_circle.XAxis.Visible = 'off'; % 隐藏 X 轴
            app.UIAxes_circle.YAxis.Visible = 'off'; % 隐藏 Y 轴
            app.UIAxes_circle.Box = 'off'; % 隐藏边框 
        end
        
        % 旋转record2
        function rotateImg(app)
            % 动态旋转
            while app.isRotating(1)
                % 检查 app 对象是否仍然有效
                if ~isvalid(app)
                    return; % 如果 app 对象无效，退出回调函数
                end

                % 确定起始角度
                if app.isRotating(2)
                    startAngle = app.currentAngle; % 从上次停止的位置继续旋转
                else
                    startAngle = 0; % 从 0 开始旋转
                end

                for angle = startAngle:5:360 % 每次旋转5度
                    if ~app.isRotating(1)
                        break; % 如果停止按钮被按下，退出循环
                    end
                    
                    % 基于原始图片重新计算旋转后的图片
                    rotatedImage = rotateImageAroundPoint([], app.imageCenter, app.imageData, angle);

                    % 更新 Image 组件的显示
                    app.Image_record3.ImageSource = rotatedImage;
                    app.currentAngle=angle;

                    % 暂停以产生动画效果
                    pause(0.05);
                end
                % 如果旋转到 360 度，重置角度
                if angle >= 360
                    app.currentAngle = 0;
                end
            end
            pause;
        end
        
        % 禁用彩蛋回调
        function offButtonAndImage(app)
            app.Button_play.Enable='off';
            app.Button_pause.Enable='off';
            app.Button_resume.Enable='off';
            app.Button_stop.Enable='off';
            app.Button_preMusic.Enable='off';
            app.Button_nextMusic.Enable='off';
            app.Image_play.Enable='off';
            app.Image_pause.Enable='off';
            app.Image_resume.Enable='off';
            app.Image_stop.Enable='off';
            app.Image_preMusic.Enable='off';
            app.Image_nextMusic.Enable='off';
            app.Image_record1.Enable='off';
            app.Image_gif.Enable='off';
            app.Image_mutsumi4.Enable='off';
            app.Image_mutsumi6.Enable='off';
            app.Image_mutsumi7.Enable='off';
            app.Image_mutsumi1.Enable='off';
            app.Image_mutsumi5.Enable='off';
            app.Image_surprise1.Enable='off';
            app.Image_surprise2.Enable='off';
            app.Image_surprise3.Enable='off';
            app.Image_surprise4.Enable='off';
            app.Image_surprise5.Enable='off';
            app.Image_mutsumi2.Enable='off';
            app.Image_mana.Enable='off';
        end
        
        % 启用彩蛋回调
        function onButtonAndImage(app)
            app.Button_play.Enable='on';
            app.Button_pause.Enable='on';
            app.Button_resume.Enable='on';
            app.Button_stop.Enable='on';
            app.Button_preMusic.Enable='on';
            app.Button_nextMusic.Enable='on';
            app.Image_play.Enable='on';
            app.Image_pause.Enable='on';
            app.Image_resume.Enable='on';
            app.Image_stop.Enable='on';
            app.Image_preMusic.Enable='on';
            app.Image_nextMusic.Enable='on';
            app.Image_record1.Enable='on';
            app.Image_gif.Enable='on';
            app.Image_mutsumi4.Enable='on';
            app.Image_mutsumi6.Enable='on';
            app.Image_mutsumi7.Enable='on';
            app.Image_mutsumi1.Enable='on';
            app.Image_mutsumi5.Enable='on';
            app.Image_surprise1.Enable='on';
            app.Image_surprise2.Enable='on';
            app.Image_surprise3.Enable='on';
            app.Image_surprise4.Enable='on';
            app.Image_surprise5.Enable='on';
            app.Image_mutsumi2.Enable='on';
            app.Image_mana.Enable='on';
        end
        
        % 双击判定
        function doubleClick=ImageClicked(app)
            % 获取当前时间
            currentTime = datetime('now');
            currentTime = posixtime(currentTime); % 转换为时间戳（秒）
        
            % 计算时间间隔
            timeInterval = currentTime - app.lastClickTime;
        
            % 判断是否为双击
            if timeInterval < 0.5 % 双击时间间隔阈值（0.5 秒）
                doubleClick=true;
            else
               doubleClick=false;
            end
        
            % 更新上一次点击时间
            app.lastClickTime = currentTime;
        end
        
        % 等比例缩放jpg图片
        function resizedImg = resizeImage(app,img)
            % 获取 Image 组件的尺寸
            imageWidth = app.Image_birth.Position(3);  % Image 组件的宽度
            imageHeight = app.Image_birth.Position(4); % Image 组件的高度

            % 获取图片的原始尺寸
            [imgHeight, imgWidth, ~] = size(img);

            % 计算缩放比例
            widthRatio = imageWidth / imgWidth;
            heightRatio = imageHeight / imgHeight;
            scale = min(widthRatio, heightRatio); % 保持宽高比

            % 按比例缩放图片
            resizedImg = imresize(img, scale);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uistack(app.Image_bg, 'bottom');

            app.Panel_hidden.Visible='off';
            
            % 获取当前日期
            currentDate = datetime('today');
            
            % 将当前日期设置为日期选择器的值
            app.DatePicker_date.Value = currentDate;
            
            app.Button_1.BackgroundColor=[0.49,0.69,0.27];
            
            [app.imageData, map] = imread(fullfile(app.appRoot,'pictures','record3.png'));
            app.imageData = ind2rgb(app.imageData, map);
            % app.Image_record3.ImageSource = app.imageData;
            app.currentAngle = 0; % 初始化旋转角度
            
            app.Image_record4.Visible="off";
            app.Image_record2.Visible="on";


            % 定义圆环的参数
            theta = linspace(0, 2*pi, 100); % 角度
            r1 = 140; % 外圆半径
            r2 = r1*0.97; % 内圆半径
            r3 = 125;
            r4 = r3*0.97;
            r5 = 110;
            r6 = r5*0.97;
        
            % 计算外圆和内圆的坐标
            x_outer = r1 * cos(theta);
            y_outer = r1 * sin(theta);
            x_inner = r2 * cos(theta);
            y_inner = r2 * sin(theta);

            x2_outer = r3 * cos(theta);
            y2_outer = r3 * sin(theta);
            x2_inner = r4 * cos(theta);
            y2_inner = r4 * sin(theta);

            x3_outer = r5 * cos(theta);
            y3_outer = r5 * sin(theta);
            x3_inner = r6 * cos(theta);
            y3_inner = r6 * sin(theta);

            % 关闭坐标轴自动缩放
            app.UIAxes_circle.XLimMode = 'manual';
            app.UIAxes_circle.YLimMode = 'manual';
        
            % 设置坐标轴范围
            xlim(app.UIAxes_circle, [-140, 140]); % X 轴范围
            ylim(app.UIAxes_circle, [-140, 140]); % Y 轴范围

            % 清除之前的图形并保持叠加
            cla(app.UIAxes_circle);
            hold(app.UIAxes_circle, 'on');
        
            % 绘制圆环
            fill(app.UIAxes_circle, [x_outer, fliplr(x_inner)], [y_outer, fliplr(y_inner)], [0.32,0.43,0.17], 'EdgeColor', 'none'); % 填充圆环
            fill(app.UIAxes_circle, [x2_outer, fliplr(x2_inner)], [y2_outer, fliplr(y2_inner)], [0.32,0.43,0.17], 'EdgeColor', 'none'); % 填充圆环
            fill(app.UIAxes_circle, [x3_outer, fliplr(x3_inner)], [y3_outer, fliplr(y3_inner)], [0.32,0.43,0.17], 'EdgeColor', 'none'); % 填充圆环
            axis(app.UIAxes_circle, 'equal'); % 设置坐标轴比例相等
        
            % 隐藏坐标轴和边框
            app.UIAxes_circle.Color=[0.94,0.94,0.94];
            app.UIAxes_circle.XAxis.Visible = 'off'; % 隐藏 X 轴
            app.UIAxes_circle.YAxis.Visible = 'off'; % 隐藏 Y 轴
            app.UIAxes_circle.Box = 'off'; % 隐藏边框


            % 音频文件存储
            audioFolder = fullfile(app.appRoot, 'mygo audio', '千早爱音'); % 构建音频文件夹的绝对路径
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioPlay = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '高松灯'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioPause = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '丰川祥子'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioResume = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '长崎素世'); % 构建音频文件夹的绝对路径  
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioStop = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '要乐奈'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioPre = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '椎名立希'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioNext = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '若叶睦'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioGif = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '三角初华'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioMu6 = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '八幡海铃'); % 构建音频文件夹的绝对路径  
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioMu4 = fullfile(audioFolder, {files.name});

            audioFolder = fullfile(app.appRoot, 'mygo audio', '祐天寺若麦'); % 构建音频文件夹的绝对路径 
            files = dir(fullfile(audioFolder, '*.wav')); % 支持 .wav 文件
            files = [files; dir(fullfile(audioFolder, '*.mp3'))]; % 支持 .mp3 文件
            app.audioMu7 = fullfile(audioFolder, {files.name});

            app.imageFiles = dir(fullfile(app.appRoot, app.imageFolder, '*.jpg')); % 获取 .jpg 文件
            app.imageFiles = [app.imageFiles; dir(fullfile(app.appRoot, app.imageFolder, '*.gif'))]; % 获取 .gif 文件


            % 隐藏坐标轴和边框
            app.UIAxes_video.Color=[0.94,0.94,0.94];
            app.UIAxes_video.XAxis.Visible = 'off'; % 隐藏 X 轴
            app.UIAxes_video.YAxis.Visible = 'off'; % 隐藏 Y 轴
            app.UIAxes_video.Box = 'off'; % 隐藏边框
        end

        % Button pushed function: Button_importMusic
        function Button_importMusicPushed(app, event)
            % 1.读取音乐文件
            [fileNames, pathName]=uigetfile({'*.mp3;*.wav;*.flac', 'Audio Files (*.mp3, *.wav, *.flac)'}, '导入音乐文件', 'defaultName', 'MultiSelect','on');
            if(isfloat(fileNames))
                return;
            elseif(ischar(fileNames))
                fileNames={fileNames};
            end

            % 2.私有属性赋值，方便其他函数使用
            app.FileNames=fileNames;
            app.PathName=pathName;
            app.FileCount=length(fileNames);

            % 3.歌曲列表框显示设置
            app.ListBox_songs.Items=fileNames;
            app.ListBox_songs.Value=fileNames{1,1};

            % 4.从音乐文件中读取播放数据并初始化播放数据
            readMusicDataAndInit(app, fileNames{1,1});
        end

        % Value changed function: ListBox_songs
        function ListBox_songsValueChanged(app, event)
            value = app.ListBox_songs.Value;
            readMusicDataAndInit(app, value);
        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            initPlayer(app); % 初始化音乐播放器对象
            if(~isempty(app.Player)) % 对象非空时
                play(app.Player, app.CurrentSample); % 播放
                app.isPlaying=true; % 播放判定置真

                % 显示音乐名称
                filePartIdx=find(app.ListBox_songs.Value == '.',1,'last');
                app.Label_name.Text=['正在播放：',app.ListBox_songs.Value(1:filePartIdx-1)];

                app.Image_record4.Visible="on";
                app.Image_record2.Visible="off";

                % 设置旋转标志
                app.isRotating = [true,false];
                rotateImg(app);
            else
                uialert(app.UIFigure, '请先导入歌曲', '警告');
                app.isPlaying=false; % 播放判定置假
            end
        end

        % Image clicked function: Image_play
        function Image_playClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_play.Enable="on";
                
                currentAudioFile = app.audioPlay{app.curIdPlay};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdPlay = app.curIdPlay + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdPlay > numel(app.audioPlay)
                    app.curIdPlay = 1;
                end
            end
        end

        % Button pushed function: Button_pause
        function Button_pausePushed(app, event)
            if(~isempty(app.Player)) % 对象非空时
                pause(app.Player); % 暂停
                app.isPlaying=false; % 播放判定置假
                filePartIdx=find(app.ListBox_songs.Value == '.',1,'last');
                app.Label_name.Text=['正在播放：',app.ListBox_songs.Value(1:filePartIdx-1)]; 

                app.Image_record4.Visible='off';
                app.Image_record2.Visible='on';
                
                % 设置旋转标志为 false
                app.isRotating = [false,false];
                % 记录当前旋转角度
                app.currentAngle = app.currentAngle;
            else
                uialert(app.UIFigure, '请先导入歌曲', '警告');
                app.isPlaying=false; % 播放判定置假
            end
        end

        % Image clicked function: Image_pause
        function Image_pauseClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_pause.Enable="on";
                
                currentAudioFile = app.audioPause{app.curIdPause};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdPause = app.curIdPause + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdPause > numel(app.audioPause)
                    app.curIdPause = 1;
                end
            end
        end

        % Button pushed function: Button_resume
        function Button_resumePushed(app, event)
            if(~isempty(app.Player)) % 对象非空时
                resume(app.Player); % 续播
                app.isPlaying=true; % 播放判定置真

                filePartIdx=find(app.ListBox_songs.Value == '.',1,'last');
                app.Label_name.Text=['正在播放：',app.ListBox_songs.Value(1:filePartIdx-1)];

                app.Image_record4.Visible="on";
                app.Image_record2.Visible="off";

                % 设置旋转标志为 true
                app.isRotating = [true,true];
                rotateImg(app);
            else
                uialert(app.UIFigure, '请先导入歌曲', '警告');
                app.isPlaying=false; % 播放判定置假
            end
        end

        % Image clicked function: Image_resume
        function Image_resumeClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_resume.Enable="on";
                
                currentAudioFile = app.audioResume{app.curIdResume};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdResume = app.curIdResume + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdResume > numel(app.audioResume)
                    app.curIdResume = 1;
                end
            end
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            if(~isempty(app.Player)) % 对象非空时
                stop(app.Player); % 停止
                app.isPlaying=false; % 播放判定置假

                app.Label_name.Text='正在播放：None';

                app.Image_record4.Visible="off";
                app.Image_record2.Visible="on";

                % 设置旋转标志为 false
                app.isRotating = [false,false];
                % 记录当前旋转角度
                app.currentAngle = app.currentAngle;
            elseif isempty(app.Player2)
                uialert(app.UIFigure, '请先导入歌曲', '警告');
                app.isPlaying=false; % 播放判定置假
            end

            if(~isempty(app.Player2))
                % 暂停音频
                stop(app.Player2);

                onButtonAndImage(app);
                app.Player2={};
                app.Label_name.Text='正在播放：None';
            end
        end

        % Image clicked function: Image_stop
        function Image_stopClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_stop.Enable="on";
                
                currentAudioFile = app.audioStop{app.curIdStop};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdStop = app.curIdStop + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdStop > numel(app.audioStop)
                    app.curIdStop = 1;
                end
            end
        end

        % Button pushed function: Button_preMusic
        function Button_preMusicPushed(app, event)
            % 1.获取当前歌曲的索引，即是第几首歌曲
            currentValue=app.ListBox_songs.Value; % 获取当前选择的歌曲名
            currentIndex=0; % 初始化当前歌曲索引
            for ii=1:app.FileCount % 循环对比
                if strcmp(currentValue, app.FileNames{1,ii})==1
                    currentIndex=ii;
                    break;
                end
            end

            % 2.根据当前歌曲索引，获取上一首歌曲名
            if (currentIndex==1) % 当前歌曲为第一首
                newValue=app.FileNames{1, app.FileCount}; 
            else % 当前歌曲不是第一首
                newValue=app.FileNames{1, currentIndex-1};
            end

            % 3.修改音乐列表框中的显示
            app.ListBox_songs.Value=newValue;

            % 4.读取上一首音乐数据，并初始化播放数据
            readMusicDataAndInit(app, newValue);

            % 5.调用按钮回调函数，立即播放
            Button_playPushed(app, event);
            
            % 6.设置旋转标志
            app.Image_record4.Visible="on";
            app.Image_record2.Visible="off";
            app.isRotating = [true,false];
            rotateImg(app);
        end

        % Image clicked function: Image_preMusic
        function Image_preMusicClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_preMusic.Enable="on";
                
                currentAudioFile = app.audioPre{app.curIdPre};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdPre = app.curIdPre + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdPre > numel(app.audioPre)
                    app.curIdPre = 1;
                end
            end
        end

        % Button pushed function: Button_nextMusic
        function Button_nextMusicPushed(app, event)
            % 1.获取当前歌曲的索引，即是第几首歌曲
            currentValue=app.ListBox_songs.Value; % 获取当前选择的歌曲名
            currentIndex=0; % 初始化当前歌曲索引
            for ii=1:app.FileCount % 循环对比
                if strcmp(currentValue, app.FileNames{1,ii})==1
                    currentIndex=ii;
                    break;
                end
            end

            % 2.根据当前歌曲索引，获取下一首歌曲名
            if (currentIndex==app.FileCount) % 当前歌曲为最后一首
                newValue=app.FileNames{1, 1}; 
            else % 当前歌曲不是最后一首
                newValue=app.FileNames{1, currentIndex+1};
            end

            % 3.修改音乐列表框中的显示
            app.ListBox_songs.Value=newValue;

            % 4.读取下一首音乐数据，并初始化播放数据
            readMusicDataAndInit(app, newValue);

            % 5.调用按钮回调函数，立即播放
            Button_playPushed(app, event);

            % 6.设置旋转标志
            app.Image_record4.Visible="on";
            app.Image_record2.Visible="off";
            app.isRotating = [true,false];
            rotateImg(app);
        end

        % Image clicked function: Image_nextMusic
        function Image_nextMusicClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_nextMusic.Enable="on";
                
                currentAudioFile = app.audioNext{app.curIdNext};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdNext = app.curIdNext + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdNext > numel(app.audioNext)
                    app.curIdNext = 1;
                end
            end
        end

        % Value changed function: Slider_progress
        function Slider_progressValueChanged(app, event)
            value = app.Slider_progress.Value;
            if~isempty(app.Player) % 当前播放器非空
                newSamplePos=floor((app.Player.TotalSamples+1)*value); % 获得新的起始播放点数
                pause(app.Player); % 暂停当前播放
                play(app.Player,newSamplePos); % 从新的指定位置处播放
                app.isPlaying=true; % 播放判定置真
                app.Image_record4.Visible="on";
                app.Image_record2.Visible="off";
            else % 当前播放器为空
                app.Slider_progress.Value=0; % 进度条置0
                app.isPlaying=false; % 播放判定置假
            end
        end

        % Value changed function: DropDown_musicStyle
        function DropDown_musicStyleValueChanged(app, event)
            value = app.DropDown_musicStyle.Value;
            app.EqType=value; % 存储该Eq类型
            if(~isempty(app.Player)) % 对象非空时
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app,event); % 调用播放按钮的回调函数，立即播放
            end
        end

        % Value changed function: Switch_humanVoice
        function Switch_humanVoiceValueChanged(app, event)
            value = app.Switch_humanVoice.Value;
            if (strcmp(value,'Off')) % 不进行人声消除
                app.IsRemoveHumanVoice=false;
            else % 进行人声消除
                app.IsRemoveHumanVoice=true;
            end
            if(~isempty(app.Player))
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app, event); % 调用回调函数，立即播放
            end
        end

        % Value changed function: DropDown_reverberation
        function DropDown_reverberationValueChanged(app, event)
            value = app.DropDown_reverberation.Value;
            app.ReverbValue=str2double(strip(value,'right','s')); % 获取混响值
            if(~isempty(app.Player)) % 对象非空时
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app,event); % 调用回调函数，立即播放
            end
        end

        % Value changed function: DropDown_speed
        function DropDown_speedValueChanged(app, event)
            value = app.DropDown_speed.Value;
            app.PlaySpeed=str2double(strip(value,'right','X')); % 获取速度值
            if(~isempty(app.Player)) % 对象非空时
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app,event); % 调用回调函数，立即播放
            end
        end

        % Menu selected function: Menu_play
        function Menu_playSelected(app, event)
            Button_playPushed(app,event);
        end

        % Menu selected function: Menu_pause
        function Menu_pauseSelected(app, event)
            Button_pausePushed(app,event);
        end

        % Menu selected function: Menu_resume
        function Menu_resumeSelected(app, event)
            Button_resumePushed(app,event);
        end

        % Menu selected function: Menu_stop
        function Menu_stopSelected(app, event)
            Button_stopPushed(app,event);
        end

        % Menu selected function: Menu_preMusic
        function Menu_preMusicSelected(app, event)
            Button_preMusicPushed(app,event);
        end

        % Menu selected function: Menu_nextMusic
        function Menu_nextMusicSelected(app, event)
            Button_nextMusicPushed(app,event);
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_musicList;
            TabGroupSelectionChanged(app, event);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_musicEffect;
            TabGroupSelectionChanged(app, event);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_waveForm;
            TabGroupSelectionChanged(app, event);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_surprise;
            TabGroupSelectionChanged(app, event);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            app.Button_1.BackgroundColor=[0.88,0.95,0.80];
            app.Button_2.BackgroundColor=[0.88,0.95,0.80];
            app.Button_3.BackgroundColor=[0.88,0.95,0.80];
            app.Button_4.BackgroundColor=[0.88,0.95,0.80];

            if selectedTab==app.Tab_musicList
                app.Button_1.BackgroundColor=[0.49,0.69,0.27];
            elseif selectedTab==app.Tab_musicEffect
                app.Button_2.BackgroundColor=[0.49,0.69,0.27];
            elseif selectedTab==app.Tab_waveForm
                app.Button_3.BackgroundColor=[0.49,0.69,0.27];
            elseif selectedTab==app.Tab_surprise
                app.Button_4.BackgroundColor=[0.49,0.69,0.27];
            end
        end

        % Value changed function: Slider_volumeAdjust
        function Slider_volumeAdjustValueChanged(app, event)
            value = app.Slider_volumeAdjust.Value;
            app.Volume=value/100; % 获取音量值
            if ~isempty(app.Player)
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app,event); % 调用回调函数，立即播放
            end
        end

        % Menu selected function: Menu_email
        function Menu_emailSelected(app, event)
            uialert(app.UIFigure, 'wangzixun@mail.nwpu.edu.cn', '学校邮件', 'Icon', 'info');
        end

        % Value changed function: DropDown_highEq
        function DropDown_highEqValueChanged(app, event)
            value = app.DropDown_highEq.Value;
            app.highEq=value; % 存储该Eq类型
            if(~isempty(app.Player)) % 对象非空时
                app.CurrentSample=app.Player.CurrentSample; % 将当前播放位置进行存储
                Button_playPushed(app,event); % 调用播放按钮的回调函数，立即播放
            end
        end

        % Image clicked function: Image_gif
        function Image_gifClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_gif.Enable="on";
                
                currentAudioFile = app.audioGif{app.curIdGif};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdGif = app.curIdGif + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdGif > numel(app.audioGif)
                    app.curIdGif = 1;
                end
            end
        end

        % Image clicked function: Image_mutsumi6
        function Image_mutsumi6Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi6.Enable="on";
                
                if ~ImageClicked(app)
                    currentAudioFile = app.audioMu6{app.curIdMu6};
    
                    % 读取音频文件
                    [y, fs] = audioread(currentAudioFile);
                
                    % 播放音频
                    sound(y, fs);
                
                    % 更新索引以指向下一个音频文件
                    app.curIdMu6 = app.curIdMu6 + 1;
                
                    % 如果索引超出范围，则重置为 1
                    if app.curIdMu6 > numel(app.audioMu6)
                        app.curIdMu6 = 1;
                    end
                else
                    app.Button_stop.Enable="on";
                    [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio','here the world 2.mp3')); % 替换为你的音频文件路径

                    % 创建 audioplayer 对象
                    app.Player2 = audioplayer(y, fs);

                    app.Label_name.Text='正在播放：here the world（初祥版）';
                
                    % 播放音频
                    play(app.Player2);

                    while ~isempty(app.Player2)
                        if ~isplaying(app.Player2)
                            break;
                        end
                        pause(0.5); % 每隔 0.5 秒检查一次
                    end

                    app.Player2={};
                    app.Label_name.Text='正在播放：None';
                end

                onButtonAndImage(app);
            end
        end

        % Image clicked function: Image_mutsumi7
        function Image_mutsumi7Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi7.Enable="on";
                
                currentAudioFile = app.audioMu7{app.curIdMu7};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdMu7 = app.curIdMu7 + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdMu7 > numel(app.audioMu7)
                    app.curIdMu7 = 1;
                end
            end
        end

        % Image clicked function: Image_mutsumi4
        function Image_mutsumi4Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi4.Enable="on";
                
                currentAudioFile = app.audioMu4{app.curIdMu4};

                % 读取音频文件
                [y, fs] = audioread(currentAudioFile);
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            
                % 更新索引以指向下一个音频文件
                app.curIdMu4 = app.curIdMu4 + 1;
            
                % 如果索引超出范围，则重置为 1
                if app.curIdMu4 > numel(app.audioMu4)
                    app.curIdMu4 = 1;
                end
            end
        end

        % Image clicked function: Image_mutsumi1
        function Image_mutsumi1Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi1.Enable="on";

                % 读取音频文件
                [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio', 'all.wav'));
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            end
        end

        % Image clicked function: Image_mutsumi5
        function Image_mutsumi5Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi5.Enable="on";

                % 读取音频文件
                [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio', '若叶睦_sing.wav'));
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            end
        end

        % Image clicked function: Image_surprise1
        function Image_surprise1Clicked(app, event)
            web('https://www.bilibili.com/video/BV17tCKYcErF/?spm_id_from=333.1387.favlist.content.click', '-browser');
        end

        % Image clicked function: Image_surprise2
        function Image_surprise2Clicked(app, event)
            web('https://www.bilibili.com/video/BV1Xzf5YUEYh/?spm_id_from=333.1387.favlist.content.click', '-browser');
            web('https://www.bilibili.com/video/BV165fJY3Eee/?spm_id_from=333.1391.0.0', '-browser');
        end

        % Image clicked function: Image_surprise3
        function Image_surprise3Clicked(app, event)
            web('https://www.bilibili.com/video/BV1jbAteBEUy/?spm_id_from=333.1387.favlist.content.click', '-browser');
        end

        % Image clicked function: Image_surprise4
        function Image_surprise4Clicked(app, event)
            createImageDialog(app.imageFiles);
        end

        % Image clicked function: Image_surprise5
        function Image_surprise5Clicked(app, event)
            web('https://www.bilibili.com/video/BV1JvPke9EWe/?spm_id_from=333.1387.favlist.content.click', '-browser');
        end

        % Image clicked function: Image_mutsumi2
        function Image_mutsumi2Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mutsumi2.Enable="on";

                % 读取音频文件
                [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio', 'here the world 1.wav'));
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            end
        end

        % Image clicked function: Image_record1
        function Image_record1Clicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_record1.Enable="on";

                % 读取音频文件
                [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio', '丰川祥子x若叶睦.mp3'));
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            uialert(app.UIFigure, ['主题：' newline ...
                '※ MyGo及AveMujica  若叶睦' newline newline ...
                '引用图片来源：' newline ...
                '※ https://www.bilibili.com/read/readlist/rl911916?spm_id_from=333.1369' newline  ...
                '※ huaban.com' newline ...
                '※ https://www.taotu.cn/pic/235723' newline ...
                '※ pixiv.net' newline ...
                '※ MyGo官方设定画集' newline ...
                '※ 室友(任钰浩)及本人(王子勋)搜集' newline newline ...
                '引用音频来源：' newline...
                '※ https://www.bilibili.com/video/BV13gs4eEEob/?spm_id_from=333.1391.0.0' newline ...
                '※ https://www.bilibili.com/video/BV1GVkhY9Eu7/?spm_id_from=333.1391.0.0' newline ...
                '※ https://www.bilibili.com/video/BV15nwLeaEwF/?spm_id_from=333.1391.0.0' newline ...
                '※ 本人裁剪' newline newline...
                '引用链接来源：' newline ...
                '※ bilibili.com' newline newline...
                '引用视频来源：' newline ...
                '※ https://www.bilibili.com/video/BV1RY9mYPE5m/?spm_id_from=333.1391.0.0' newline...
                '※ https://www.bilibili.com/video/BV1WNRPYtEtD/?spm_id_from=333.1391.0.0' newline newline...
                '本作品为个人制作，仅供课程考核需求，非涉及商用。'], '材料引用', 'Icon', 'info');
        end

        % Value changed function: DatePicker_date
        function DatePicker_dateValueChanged(app, event)
            if app.displaySurprise
                value = app.DatePicker_date.Value;
                currentDate=datetime("today");
                curMonth=month(currentDate);
                curDay=day(currentDate);
                curDate=[curMonth curDay];
    
                selectedMonth=month(value);
                selectedDay=day(value);
                selectedDate=[selectedMonth selectedDay];
    
                % 生日矩阵
                birthday=[1 14;   2 14;  2 22;    4 7;     5 27;     6 1;     6 26;     8 9;    9 8;   11 22];
                %        若叶睦 丰川祥子 要乐奈 八幡海铃 长崎素世 祐天寺若麦 三角初华 椎名立希 千早爱音 高松灯
                % 判断选择日期是否是角色生日
                is_birthday= any(all(ismember(birthday, selectedDate, 'rows'), 2));
    
                app.Panel_hidden.Visible="on";
                app.TabGroup.SelectedTab=app.Tab_surprise;

                if is_birthday
                    birthIndex = find(ismember(birthday, selectedDate, 'rows')); % 生日所在行数
                    switch birthIndex
                        case 1
                            name="若叶睦";     
                        case 2
                            name="丰川祥子";
                        case 3
                            name="要乐奈";
                        case 4
                            name="八幡海铃";
                        case 5
                            name="长崎素世";
                        case 6
                            name="祐天寺若麦";
                        case 7
                            name="三角初华";
                        case 8
                            name="椎名立希";
                        case 9
                            name="千早爱音";
                        case 10
                            name="高松灯";   
                    end
                    
                    name=strrep(char(name), newline, '');
                    app.imageFileBirth=fullfile(app.appRoot,'pictures',[name,'.jpg']);
                    img=imread(app.imageFileBirth); 
                    app.Image_birth.ImageSource=resizeImage(app,img);

                    if isequal(curDate,selectedDate)
                        app.Label_birthday.Text=['今天是',name,'的生日!' ...
                            '有猫咪甜甜圈哦，要吃吗？'];
                    else
                        app.Label_birthday.Text=[num2str(selectedMonth),'月',num2str(selectedDay),'日', ...
                            '是',name,'的生日!有猫咪甜甜圈哦，要吃吗？'];
                    end
                else
                    app.Label_birthday.Text='有猫咪甜甜圈哦，要吃吗？';
                    app.imageFileBirth=fullfile(app.appRoot,'pictures','default.jpg');
                    img=imread(app.imageFileBirth); 
                    app.Image_birth.ImageSource=resizeImage(app,img);
                end
            end
        end

        % Image clicked function: Image_mana
        function Image_manaClicked(app, event)
            % 彩蛋
            if ~app.isPlaying
                offButtonAndImage(app);
                app.Image_mana.Enable="on";

                % 读取音频文件
                [y, fs] = audioread(fullfile(app.appRoot, 'mygo audio', '纯田真奈', '纯田真奈.wav'));
            
                % 播放音频
                sound(y, fs);

                onButtonAndImage(app);
            end
        end

        % Button pushed function: Button_quit
        function Button_quitPushed(app, event)
            app.videoReader={};
            app.videoPlaying=false;
            app.Panel_hidden.Visible='off';
            app.displaySurprise=false;
        end

        % Button pushed function: Button_restore
        function Button_restorePushed(app, event)
            % 恢复回调函数
            app.displaySurprise=true;
            DatePicker_dateValueChanged(app, event);
        end

        % Button pushed function: Button_videoPlay
        function Button_videoPlayPushed(app, event)
            app.Image_birth.Visible='off';
            if isempty(app.videoReader)
                videoFile=fullfile(app.appRoot,'video','video1.mp4'); % 视频绝对路径
                app.videoIndex=1;
                app.videoReader=VideoReader(videoFile); % 创建视频读取对象
                [app.vAudioData, app.vSampleRate]=audioread(videoFile);
                app.audioVideoPlayer=audioplayer(app.vAudioData,app.vSampleRate);
                app.videoPlaying=true;
                app.videoPaused=false;
                app.Button_videoPlay.Text="暂停";
                play(app.audioVideoPlayer);
    
                while app.videoPlaying && hasFrame(app.videoReader)
                    if ~app.videoPaused
                        frame=readFrame(app.videoReader);
                        imshow(frame, 'Parent', app.UIAxes_video);
                        lora=app.Spinner_lora.Value;
                        pause(1/(app.videoReader.FrameRate*lora));
                    else
                        pause(0.1); % 短暂暂停以避免占用过多CPU
                    end
                end
                
                % 播放结束
                if ~app.videoPaused
                    app.videoPlaying=false;
                    app.Button_videoPlay.Text='播放';
                end
            elseif app.videoPlaying && ~app.videoPaused && app.videoIndex==1
            % 暂停播放
                app.videoPaused=true;
                app.Button_videoPlay.Text='续播';
                pause(app.audioVideoPlayer); % 暂停音频
            elseif app.videoPlaying && app.videoPaused && app.videoIndex==1
            % 续播视频
                app.videoPaused=false;
                app.Button_videoPlay.Text='暂停';
                resume(app.audioVideoPlayer); % 恢复音频
            end
        end

        % Button pushed function: Button_videoStop
        function Button_videoStopPushed(app, event)
            % 停止播放
            app.videoPlaying=false;
            app.videoPaused=false;
            app.Button_videoPlay.Text='播放';
            cla(app.UIAxes_video); % 清空Axes

            % 停止音频
            if ~isempty(app.audioVideoPlayer)
                stop(app.audioVideoPlayer);
            end

            app.videoReader={};
            app.audioVideoPlayer={};
            app.Image_birth.Visible='on';
        end

        % Button pushed function: Button_videoPlay_2
        function Button_videoPlay_2Pushed(app, event)
            app.Image_birth.Visible='off';
            if isempty(app.videoReader)
                videoFile=fullfile(app.appRoot,'video','video2.mp4'); % 视频绝对路径
                app.videoIndex=2;
                app.videoReader=VideoReader(videoFile); % 创建视频读取对象
                [app.vAudioData, app.vSampleRate]=audioread(videoFile);
                app.audioVideoPlayer=audioplayer(app.vAudioData,app.vSampleRate);
                app.videoPlaying=true;
                app.videoPaused=false;
                app.Button_videoPlay_2.Text="暂停";
                play(app.audioVideoPlayer);
    
                while app.videoPlaying && hasFrame(app.videoReader)
                    if ~app.videoPaused
                        frame=readFrame(app.videoReader);
                        imshow(frame, 'Parent', app.UIAxes_video);
                        lora=app.Spinner_lora.Value;
                        pause(1/(app.videoReader.FrameRate*lora));
                    else
                        pause(0.1); % 短暂暂停以避免占用过多CPU
                    end
                end
                
                % 播放结束
                if ~app.videoPaused
                    app.videoPlaying=false;
                    app.Button_videoPlay_2.Text='不要点这里';
                end
            elseif app.videoPlaying && ~app.videoPaused && app.videoIndex==2
            % 暂停播放
                app.videoPaused=true;
                app.Button_videoPlay_2.Text='续播';
                pause(app.audioVideoPlayer); % 暂停音频
            elseif app.videoPlaying && app.videoPaused && app.videoIndex==2
            % 续播视频
                app.videoPaused=false;
                app.Button_videoPlay_2.Text='暂停';
                resume(app.audioVideoPlayer); % 恢复音频
            end
        end

        % Image clicked function: Image_birth
        function Image_birthClicked(app, event)
            birthTemp=find(app.imageFileBirth == '.',1,'last');
            birthPng=[app.imageFileBirth(1:birthTemp-1),'.png'];

            systemType = computer;
            switch systemType
                case {'PCWIN', 'PCWIN64'}
                    winopen(birthPng);
                case 'MACI64'
                    system(['open "', birthPng, '"']);
                case 'GLNXA64'
                    system(['xdg-open "', birthPng, '"']);
                otherwise
                    error('不支持的操作系统: %s', systemType);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_playControl = uimenu(app.UIFigure);
            app.Menu_playControl.Text = '播放控制';
            app.Menu_play = uimenu(app.Menu_playControl);
            app.Menu_play.MenuSelectedFcn = createCallbackFcn(app, @Menu_playSelected, true);
            app.Menu_play.Text = '播放';
            app.Menu_pause = uimenu(app.Menu_playControl);
            app.Menu_pause.MenuSelectedFcn = createCallbackFcn(app, @Menu_pauseSelected, true);
            app.Menu_pause.Text = '暂停';
            app.Menu_resume = uimenu(app.Menu_playControl);
            app.Menu_resume.MenuSelectedFcn = createCallbackFcn(app, @Menu_resumeSelected, true);
            app.Menu_resume.Text = '续播';
            app.Menu_stop = uimenu(app.Menu_playControl);
            app.Menu_stop.MenuSelectedFcn = createCallbackFcn(app, @Menu_stopSelected, true);
            app.Menu_stop.Text = '停止';
            app.Menu_preMusic = uimenu(app.Menu_playControl);
            app.Menu_preMusic.MenuSelectedFcn = createCallbackFcn(app, @Menu_preMusicSelected, true);
            app.Menu_preMusic.Text = '上一首';
            app.Menu_nextMusic = uimenu(app.Menu_playControl);
            app.Menu_nextMusic.MenuSelectedFcn = createCallbackFcn(app, @Menu_nextMusicSelected, true);
            app.Menu_nextMusic.Text = '下一首';
            app.Menu_contact = uimenu(app.UIFigure);
            app.Menu_contact.Text = '联系作者';
            app.Menu_email = uimenu(app.Menu_contact);
            app.Menu_email.MenuSelectedFcn = createCallbackFcn(app, @Menu_emailSelected, true);
            app.Menu_email.Text = '学校邮件';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '说明';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '材料引用';
            app.Image_bg = uiimage(app.UIFigure);
            app.Image_bg.ImageSource = fullfile(pathToMLAPP, 'pictures', 'background.jpg');
            app.Image_record3 = uiimage(app.UIFigure);
            app.Image_record3.ImageSource = fullfile(pathToMLAPP, 'pictures', 'record3.png');
            app.Image_record1 = uiimage(app.UIFigure);
            app.Image_record1.ImageClickedFcn = createCallbackFcn(app, @Image_record1Clicked, true);
            app.Image_record1.ImageSource = fullfile(pathToMLAPP, 'pictures', 'record1.png');
            app.Image_record2 = uiimage(app.UIFigure);
            app.Image_record2.ImageSource = fullfile(pathToMLAPP, 'pictures', 'record2.png');
            app.Label_title = uilabel(app.UIFigure);
            app.Label_title.Text = '睦头の音乐播放器';
            app.Image_cucumber = uiimage(app.UIFigure);
            app.Image_cucumber.ImageSource = fullfile(pathToMLAPP, 'pictures', 'cucumber.png');
            app.Panel_playControl = uipanel(app.UIFigure);
            app.Panel_playControl.Title = '播放控制';
            app.Button_play = uibutton(app.Panel_playControl, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Text = '播放';
            app.Button_pause = uibutton(app.Panel_playControl, 'push');
            app.Button_pause.ButtonPushedFcn = createCallbackFcn(app, @Button_pausePushed, true);
            app.Button_pause.Text = '暂停';
            app.Button_resume = uibutton(app.Panel_playControl, 'push');
            app.Button_resume.ButtonPushedFcn = createCallbackFcn(app, @Button_resumePushed, true);
            app.Button_resume.Text = '续播';
            app.Button_stop = uibutton(app.Panel_playControl, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Text = '停止';
            app.Button_preMusic = uibutton(app.Panel_playControl, 'push');
            app.Button_preMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_preMusicPushed, true);
            app.Button_preMusic.Text = '上一首';
            app.Button_nextMusic = uibutton(app.Panel_playControl, 'push');
            app.Button_nextMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_nextMusicPushed, true);
            app.Button_nextMusic.Text = '下一首';
            app.Image_play = uiimage(app.Panel_playControl);
            app.Image_play.ImageClickedFcn = createCallbackFcn(app, @Image_playClicked, true);
            app.Image_play.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice1.png');
            app.Image_pause = uiimage(app.Panel_playControl);
            app.Image_pause.ImageClickedFcn = createCallbackFcn(app, @Image_pauseClicked, true);
            app.Image_pause.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice2.png');
            app.Image_resume = uiimage(app.Panel_playControl);
            app.Image_resume.ImageClickedFcn = createCallbackFcn(app, @Image_resumeClicked, true);
            app.Image_resume.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice3.png');
            app.Image_stop = uiimage(app.Panel_playControl);
            app.Image_stop.ImageClickedFcn = createCallbackFcn(app, @Image_stopClicked, true);
            app.Image_stop.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice4.png');
            app.Image_preMusic = uiimage(app.Panel_playControl);
            app.Image_preMusic.ImageClickedFcn = createCallbackFcn(app, @Image_preMusicClicked, true);
            app.Image_preMusic.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice5.png');
            app.Image_nextMusic = uiimage(app.Panel_playControl);
            app.Image_nextMusic.ImageClickedFcn = createCallbackFcn(app, @Image_nextMusicClicked, true);
            app.Image_nextMusic.ImageSource = fullfile(pathToMLAPP, 'pictures', 'choice6.png');
            app.Panel_progress = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_progress);
            app.Label.Text = '音乐进度：';
            app.Slider_progress = uislider(app.Panel_progress);
            app.Slider_progress.Limits = [0 1];
            app.Slider_progress.MajorTicks = [];
            app.Slider_progress.ValueChangedFcn = createCallbackFcn(app, @Slider_progressValueChanged, true);
            app.Slider_progress.MinorTicks = [];
            app.Label_time = uilabel(app.Panel_progress);
            app.Label_time.Text = '00:00:00 / 00:00:00';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_musicList = uitab(app.TabGroup);
            app.Tab_musicList.Title = '歌曲列表';
            app.Label_2 = uilabel(app.Tab_musicList);
            app.Label_2.Text = '';
            app.ListBox_songs = uilistbox(app.Tab_musicList);
            app.ListBox_songs.Items = {};
            app.ListBox_songs.ValueChangedFcn = createCallbackFcn(app, @ListBox_songsValueChanged, true);
            app.ListBox_songs.Value = {};
            app.Image_mutsumi6 = uiimage(app.Tab_musicList);
            app.Image_mutsumi6.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi6Clicked, true);
            app.Image_mutsumi6.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi6.png');
            app.Image_mutsumi4 = uiimage(app.Tab_musicList);
            app.Image_mutsumi4.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi4Clicked, true);
            app.Image_mutsumi4.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi4.png');
            app.Image_mutsumi7 = uiimage(app.Tab_musicList);
            app.Image_mutsumi7.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi7Clicked, true);
            app.Image_mutsumi7.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi5.png');
            app.Button_importMusic = uibutton(app.Tab_musicList, 'push');
            app.Button_importMusic.ButtonPushedFcn = createCallbackFcn(app, @Button_importMusicPushed, true);
            app.Button_importMusic.Text = '导入歌曲';
            app.Panel_doubleCck = uipanel(app.Tab_musicList);
            app.DoubleClickImageSurpriseLabel = uilabel(app.Panel_doubleCck);
            app.DoubleClickImageSurpriseLabel.Text = 'Double Click Image Surprise';
            app.Tab_musicEffect = uitab(app.TabGroup);
            app.Tab_musicEffect.Title = '音效控制';
            app.Panel_musicEffect = uipanel(app.Tab_musicEffect);
            app.Label_3 = uilabel(app.Panel_musicEffect);
            app.Label_3.Text = '音乐风格：';
            app.DropDown_musicStyle = uidropdown(app.Panel_musicEffect);
            app.DropDown_musicStyle.Items = {'Normal', 'Jazz', 'Rock', 'Metal', 'Bass'};
            app.DropDown_musicStyle.ValueChangedFcn = createCallbackFcn(app, @DropDown_musicStyleValueChanged, true);
            app.DropDown_musicStyle.Value = 'Normal';
            app.Label_4 = uilabel(app.Panel_musicEffect);
            app.Label_4.Text = '播放速度：';
            app.DropDown_speed = uidropdown(app.Panel_musicEffect);
            app.DropDown_speed.Items = {'0.5X', '0.75X', '1X', '1.25X', '1.5X', '1.75X', '2X'};
            app.DropDown_speed.ValueChangedFcn = createCallbackFcn(app, @DropDown_speedValueChanged, true);
            app.DropDown_speed.Value = '1X';
            app.Label_5 = uilabel(app.Panel_musicEffect);
            app.Label_5.Text = '混响：';
            app.DropDown_reverberation = uidropdown(app.Panel_musicEffect);
            app.DropDown_reverberation.Items = {'0.0s', '0.04s', '0.08s', '0.12s', '0.16s', '0.2s'};
            app.DropDown_reverberation.ValueChangedFcn = createCallbackFcn(app, @DropDown_reverberationValueChanged, true);
            app.DropDown_reverberation.Value = '0.0s';
            app.Label_6 = uilabel(app.Panel_musicEffect);
            app.Label_6.Text = '人声消除：';
            app.Switch_humanVoice = uiswitch(app.Panel_musicEffect, 'slider');
            app.Switch_humanVoice.ValueChangedFcn = createCallbackFcn(app, @Switch_humanVoiceValueChanged, true);
            app.Label_8 = uilabel(app.Panel_musicEffect);
            app.Label_8.Text = '高级音效：';
            app.DropDown_highEq = uidropdown(app.Panel_musicEffect);
            app.DropDown_highEq.Items = {'None', '音乐厅', '电音', '交响乐', 'Live现场', 'HiFi环绕', '合唱', '镶边', '相位', '立体声拓展', '回声'};
            app.DropDown_highEq.ValueChangedFcn = createCallbackFcn(app, @DropDown_highEqValueChanged, true);
            app.DropDown_highEq.Value = 'None';
            app.Image_mutsumi1 = uiimage(app.Tab_musicEffect);
            app.Image_mutsumi1.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi1Clicked, true);
            app.Image_mutsumi1.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi1.png');
            app.Tab_waveForm = uitab(app.TabGroup);
            app.Tab_waveForm.Title = '时域波形';
            app.UIAxes_all = uiaxes(app.Tab_waveForm);
            xlabel(app.UIAxes_all, 'Time (s)')
            ylabel(app.UIAxes_all, 'Magnitude')
            zlabel(app.UIAxes_all, 'Z')
            app.UIAxes_current = uiaxes(app.Tab_waveForm);
            xlabel(app.UIAxes_current, 'Time (s)')
            ylabel(app.UIAxes_current, 'Magnitude')
            zlabel(app.UIAxes_current, 'Z')
            app.UIAxes_current.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Tab_surprise = uitab(app.TabGroup);
            app.Tab_surprise.Title = '彩蛋';
            app.UIAxes_circle = uiaxes(app.Tab_surprise);
            app.UIAxes_circle.XTick = [];
            app.UIAxes_circle.YTick = [];
            app.Image_mutsumi5 = uiimage(app.Tab_surprise);
            app.Image_mutsumi5.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi5Clicked, true);
            app.Image_mutsumi5.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi3.png');
            app.Panel_surprise0 = uipanel(app.Tab_surprise);
            app.Label_surprise0 = uilabel(app.Panel_surprise0);
            app.Label_surprise0.Text = {'非播放状态下点击'; '人物头像有惊喜！'};
            app.Image_surprise1 = uiimage(app.Tab_surprise);
            app.Image_surprise1.ImageClickedFcn = createCallbackFcn(app, @Image_surprise1Clicked, true);
            app.Image_surprise1.ImageSource = fullfile(pathToMLAPP, 'pictures', '1.png');
            app.Image_surprise2 = uiimage(app.Tab_surprise);
            app.Image_surprise2.ImageClickedFcn = createCallbackFcn(app, @Image_surprise2Clicked, true);
            app.Image_surprise2.ImageSource = fullfile(pathToMLAPP, 'pictures', '2.png');
            app.Image_surprise3 = uiimage(app.Tab_surprise);
            app.Image_surprise3.ImageClickedFcn = createCallbackFcn(app, @Image_surprise3Clicked, true);
            app.Image_surprise3.ImageSource = fullfile(pathToMLAPP, 'pictures', '3.png');
            app.Image_surprise4 = uiimage(app.Tab_surprise);
            app.Image_surprise4.ImageClickedFcn = createCallbackFcn(app, @Image_surprise4Clicked, true);
            app.Image_surprise4.ImageSource = fullfile(pathToMLAPP, 'pictures', '4.png');
            app.Image_surprise5 = uiimage(app.Tab_surprise);
            app.Image_surprise5.ImageClickedFcn = createCallbackFcn(app, @Image_surprise5Clicked, true);
            app.Image_surprise5.ImageSource = fullfile(pathToMLAPP, 'pictures', '5.png');
            app.Label_name = uilabel(app.UIFigure);
            app.Label_name.Text = '正在播放：None';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '歌曲列表';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '音效控制';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '时域波形';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '彩蛋';
            app.Image_gif = uiimage(app.UIFigure);
            app.Image_gif.ImageClickedFcn = createCallbackFcn(app, @Image_gifClicked, true);
            app.Image_gif.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi4.gif');
            app.Panel_volumeAdjust = uipanel(app.UIFigure);
            app.Label_7 = uilabel(app.Panel_volumeAdjust);
            app.Label_7.Text = '音量调节：';
            app.Slider_volumeAdjust = uislider(app.Panel_volumeAdjust);
            app.Slider_volumeAdjust.ValueChangedFcn = createCallbackFcn(app, @Slider_volumeAdjustValueChanged, true);
            app.Slider_volumeAdjust.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider_volumeAdjust.Value = 70;
            app.Image_mutsumi2 = uiimage(app.UIFigure);
            app.Image_mutsumi2.ImageClickedFcn = createCallbackFcn(app, @Image_mutsumi2Clicked, true);
            app.Image_mutsumi2.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mutsumi2.png');
            app.Image_record4 = uiimage(app.UIFigure);
            app.Image_record4.ImageSource = fullfile(pathToMLAPP, 'pictures', 'record4.png');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.DatePicker_date = uidatepicker(app.UIFigure);
            app.DatePicker_date.DisplayFormat = 'uuuu/MM/dd';
            app.DatePicker_date.ValueChangedFcn = createCallbackFcn(app, @DatePicker_dateValueChanged, true);
            app.Panel_hidden = uipanel(app.UIFigure);
            app.UIAxes_video = uiaxes(app.Panel_hidden);
            app.UIAxes_video.XTick = [];
            app.UIAxes_video.YTick = [];
            app.Image_mana = uiimage(app.Panel_hidden);
            app.Image_mana.ImageClickedFcn = createCallbackFcn(app, @Image_manaClicked, true);
            app.Image_mana.ImageSource = fullfile(pathToMLAPP, 'pictures', 'mana.png');
            app.Image_doughnut = uiimage(app.Panel_hidden);
            app.Image_doughnut.ImageSource = fullfile(pathToMLAPP, 'pictures', 'doughnut.png');
            app.Label_birthday = uilabel(app.Panel_hidden);
            app.Label_birthday.Text = '有猫咪甜甜圈哦，要吃吗？';
            app.Button_videoPlay = uibutton(app.Panel_hidden, 'push');
            app.Button_videoPlay.ButtonPushedFcn = createCallbackFcn(app, @Button_videoPlayPushed, true);
            app.Button_videoPlay.Text = '播放';
            app.Button_videoStop = uibutton(app.Panel_hidden, 'push');
            app.Button_videoStop.ButtonPushedFcn = createCallbackFcn(app, @Button_videoStopPushed, true);
            app.Button_videoStop.Text = '停止';
            app.Button_quit = uibutton(app.Panel_hidden, 'push');
            app.Button_quit.ButtonPushedFcn = createCallbackFcn(app, @Button_quitPushed, true);
            app.Button_quit.Text = '退出';
            app.Button_videoPlay_2 = uibutton(app.Panel_hidden, 'push');
            app.Button_videoPlay_2.ButtonPushedFcn = createCallbackFcn(app, @Button_videoPlay_2Pushed, true);
            app.Button_videoPlay_2.Text = '不要点这里';
            app.Spinner_lora = uispinner(app.Panel_hidden);
            app.Spinner_lora.Step = 0.1;
            app.Spinner_lora.Limits = [0.8 3];
            app.Spinner_lora.Value = 1;
            app.Label_lora = uilabel(app.Panel_hidden);
            app.Label_lora.Text = '微调视频播放速度';
            app.Image_birth = uiimage(app.Panel_hidden);
            app.Image_birth.ImageClickedFcn = createCallbackFcn(app, @Image_birthClicked, true);
            app.Button_restore = uibutton(app.UIFigure, 'push');
            app.Button_restore.ButtonPushedFcn = createCallbackFcn(app, @Button_restorePushed, true);
            app.Button_restore.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mutsumis_Music_Player

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end